classdef AppBuild < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ShhAggregationFigurePlotLabel  matlab.ui.control.Label
        LargestmonomerassociationEditFieldLabel  matlab.ui.control.Label
        LargestmonomerassociationEditField  matlab.ui.control.NumericEditField
        ParametersPanel                matlab.ui.container.Panel
        MonomerinputProteinsDayEditFieldLabel  matlab.ui.control.Label
        MonomerinputProteinsDayEditField  matlab.ui.control.NumericEditField
        Sink1DayEditFieldLabel         matlab.ui.control.Label
        Sink1DayEditField              matlab.ui.control.NumericEditField
        HSPGAdditionRateParticlesDayEditFieldLabel  matlab.ui.control.Label
        HSPGAdditionRateParticlesDayEditField  matlab.ui.control.NumericEditField
        LipoproteinAdditionRateParticlesDayEditFieldLabel  matlab.ui.control.Label
        LipoproteinAdditionRateParticlesDayEditField  matlab.ui.control.NumericEditField
        MultimerisationRate1MultimerDayLabel  matlab.ui.control.Label
        MultimerisationRate1MultimerDayEditField  matlab.ui.control.NumericEditField
        HSPGRecruitmentRate1MultimerDayEditFieldLabel  matlab.ui.control.Label
        HSPGRecruitmentRate1MultimerDayEditField  matlab.ui.control.NumericEditField
        LipoproteinRecruitmentRate1MonomerDayEditFieldLabel  matlab.ui.control.Label
        LipoproteinRecruitmentRate1MonomerDayEditField  matlab.ui.control.NumericEditField
        ResetParametersButton          matlab.ui.control.Button
        AdditionalPanel                matlab.ui.container.Panel
        LargestRecruitableMultimerEditFieldLabel  matlab.ui.control.Label
        LargestRecruitableMultimerEditField  matlab.ui.control.NumericEditField
        FigureOptionsPanel             matlab.ui.container.Panel
        PrinttofigureEditFieldLabel    matlab.ui.control.Label
        PrinttofigureEditField         matlab.ui.control.NumericEditField
        CheckBox                       matlab.ui.control.CheckBox
        DefaultxAxisLimitsLabel        matlab.ui.control.Label
        MinEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        GammaDistributionOverlayLabel  matlab.ui.control.Label
        CheckBox_2                     matlab.ui.control.CheckBox
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        GenerateButton                 matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        CellAssociatedButton           matlab.ui.control.RadioButton
        DispersedButton                matlab.ui.control.RadioButton
    end

    
    methods (Access = private)
        
        %% Making multimers. (Add)
        function OUT=MMadd(app,y,CC,MM)%,n_size,p_size,eq_n)
            OUT=0;
            for i=1:floor(CC/2)
                OUT=OUT + ( MM(i,CC-i)*y(i)*y(CC-i) );
                % disp(['MM(' num2str(i) ',' num2str(CC-i) ')*y(' num2str(i) ')*y(' num2str(CC-i) ')'])
            end
        end
        %% Taking multimers (Remove)
        function OUT=MMsub(app,y,CC,MM,n_size)
            OUT=0;
            if CC*2>n_size
                for i=1:(n_size-CC)
                    OUT=OUT + ( MM(CC,i)*y(CC)*y(i) );
                    %     disp(['MM(' num2str(CC) ',' num2str(i) ')*y(' num2str(CC) ')*y(' num2str(i) ')'])
                end
            else
                for i=1:(n_size-CC)
                    OUT=OUT + ( MM(CC,i)*y(CC)*y(i) );
                    %     disp(['MM(' num2str(CC) ',' num2str(i) ')*y(' num2str(CC) ')*y(' num2str(i) ')'])
                end
                OUT=OUT + MM(CC,CC)*y(CC)*y(CC) ;
                %     disp(['MM(' num2str(CC) ',' num2str(CC) ')*y(' num2str(CC) ')*y(' num2str(CC) ')'])
            end
        end
        %% Taking monomers for Lpp (Remove)
        function OUT=MLsub(app,y,CC,LL,n_size)
            OUT=0;
            for i=1:(n_size)
                OUT=OUT+ ( LL(i)*y(CC)*y(2*n_size+1+i) );
                %     disp(['LL(' num2str(i) ')*y(' num2str(CC) ')*y(' num2str(2*n_size+1+i) ')'])
            end
        end
        %% Taking multimers for HSPGs (Remove from multimers)
        function OUT=MHsub(app,y,CC,HH,n_size,p_size)
            OUT=0;
            if CC<=p_size
                for i=1:(n_size-CC+1)
                    OUT=OUT+ (HH(i)*y(CC)*y(n_size+i));
                    %     disp(['HH(' num2str(i) ')*y(' num2str(CC) ')*y(' num2str(n_size+i) ')'])
                end
            end
        end
        %% HSPGs recruiting multimers.
        function OUT=HH_sub(app,y,CC,HH,n_size,p_size)
            OUT=0;
            LG_HSPG=(2*n_size+1); U_L = min(p_size,LG_HSPG-CC);
            for i=2:U_L
                OUT=OUT+ ( HH(CC-n_size)*y(CC)*y(i) );
                %     disp(['HH(' num2str(CC-n_size) ')*y(' num2str(CC) ')*y(' num2str(i) ')'])
            end
        end
        %% Forming HSPG Aggregates from multimers.
        function OUT=HH_add(app,y,CC,HH,n_size,p_size)
            OUT=0;
            U_L = min(p_size,CC-(n_size+1));
            for i=2:U_L
                OUT=OUT+ ( HH((CC-i)-(n_size))*y(i)*y(CC-i) );
                %     disp(['HH(' num2str(  (CC-i)-(n_size) ) ')*y(' num2str(i) ')*y(' num2str(CC-i) ')'])
            end
        end
        
        function dy=SysGen(app,t,y,P_rm,n_size,p_size,eq_n)
            %% Parameters -------------------------------------------------------------
            dy=zeros(eq_n,1);
            SRSM = P_rm(1);
            SRSH = P_rm(2);
            SRSL = P_rm(3);
            SnK  = P_rm(4);
            MM   = P_rm(5)*ones(n_size-1,n_size-1);
            HH   = P_rm(6)*ones(1,n_size+1);
            LL   = P_rm(7)*ones(1,n_size);
            
            %% Multimers --------------------------------------------------------------
            CC=1;
            dy(1) = SRSM + app.MMadd(y,CC,MM) - app.MMsub(y,CC,MM,n_size) - app.MLsub(y,CC,LL,n_size) - SnK*y(CC);
            
            for CC=2:n_size
                dy(CC) = app.MMadd(y,CC,MM) - app.MMsub(y,CC,MM,n_size) - app.MHsub(y,CC,HH,n_size,p_size) - SnK*y(CC);
            end
            
            %% HSPGs ------------------------------------------------------------------
            
            CC = n_size + 1;
            dy(CC) = SRSH - app.HH_sub(y,CC,HH,n_size,p_size);
            
            CC = n_size + 2;
            dy(CC) = 0;  %  < ------------- HSPGs can't recruit monomers.
            
            for CC=(n_size+3):(2*n_size+1)
                dy(CC) = app.HH_add(y,CC,HH,n_size,p_size) - app.HH_sub(y,CC,HH,n_size,p_size) - SnK*y(CC);
            end
            
            %% Lipoprotein ------------------------------------------------------------
            
            CC = n_size*2 + 2;
            dy(CC) = SRSL - LL(1)*y(1)*y(CC) ;
            
            for CC=(n_size*2+3):(n_size*3+1)
                dy(CC) = LL(CC-(n_size*2+2))*y(1)*y(CC-1)  -  LL(CC-(n_size*2+1))*y(1)*y(CC) - SnK*y(CC);
            end
            
            CC = n_size*3+2;
            dy(CC) = LL(CC-(n_size*2+2))*y(1)*y(CC-1)  - SnK*y(CC);
            
            %% Sink (Dispersal Terms) -------------------------------------------------
            
            snk_vals=[1:n_size (n_size+2):(n_size*2+1) (n_size*2+3):(n_size*3+2)];
            i_c=0;
            for i=snk_vals
                i_c=i_c+1;
                dy(n_size*3+2+i_c)=y(i)*SnK;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.GenerateButton.Text = 'Loading...';
            app.GenerateButton.BackgroundColor = [0.86,0.36,0.28]; 
            pause(0.5)
                clear gca
            parameters=[app.MonomerinputProteinsDayEditField.Value
                 app.HSPGAdditionRateParticlesDayEditField.Value
                 app.LipoproteinAdditionRateParticlesDayEditField.Value
                 app.Sink1DayEditField.Value
                 app.MultimerisationRate1MultimerDayEditField.Value
                 app.HSPGRecruitmentRate1MultimerDayEditField.Value
                 app.LipoproteinRecruitmentRate1MonomerDayEditField.Value];
             n_size = app.LargestmonomerassociationEditField.Value;
             p_size = app.LargestRecruitableMultimerEditField.Value;
                         
             %% Solver.
             eq_n   = (6*n_size)+2;
             SX_0=zeros(1,eq_n);
             clear Abstol; Abstol(1:eq_n)=1e-12; options=odeset('RelTol', 1e-6, 'AbsTol', Abstol);
             
             [T,Y]=ode15s(@app.SysGen, [0 1],SX_0,options,parameters,n_size,p_size,eq_n);
             
             %% Figure Plotter #########################################################

             figure(app.PrinttofigureEditField.Value)
             clf
             
             XX=linspace(1,n_size,n_size);
             
             if app.CellAssociatedButton.Value == true
                 Vals=[Y(end,1:n_size) Y(end,(n_size+2):(n_size*2+1)) Y(end,(n_size*2+3):(n_size*3+2))];
             else
                 Vals=[Y(end, (n_size*3+3):(n_size*6+2))];
             end
             
             Monomer_Bar_Pre=Vals(1); Monomer_Bar=[Monomer_Bar_Pre zeros(1,n_size-1)];
             Multimer_Bar_Pre=Vals(2:n_size); Multimer_Bar=[0 Multimer_Bar_Pre];
             HSPG_Bar=Vals((n_size+1):(n_size*2));
             Lipoprotein_Bar=Vals((n_size*2+1):(n_size*3));
             Combine_Bar=[Monomer_Bar;Multimer_Bar;HSPG_Bar;Lipoprotein_Bar]';
             Bar_Handle=bar(Combine_Bar,'BarLayout','stacked','LineStyle','none');
             
             set(Bar_Handle(1),'DisplayName','Monomers','FaceColor',[0 0 0],'LineStyle','-');
             set(Bar_Handle(2),'DisplayName','Multimerisation','FaceColor',[0 0.333333333333333 0.831372549019608],'BarWidth',0.9);
             set(Bar_Handle(3),'DisplayName','HSPG','FaceColor',[0.655737704918033 0.0819672131147541 0.0819672131147541]);
             set(Bar_Handle(4),'DisplayName','Lipoprotein','LineWidth',0.1,'FaceColor',[0 1 0.498039215686275],'BarWidth',0.9);
             
             set(gca,'FontSize',15)
             
             xlabel('Number of Monomers in Aggregate^{ }','FontSize',28)
             ylabel({'Number of Aggregates_{ }'},'FontSize',35)
             
             if app.CellAssociatedButton.Value == true
                 title('Distribution of Aggregates (Cell Associated)','FontSize',30);
             else
                 title('Distribution of Aggregates (Dispersed)','FontSize',30);
             end
             
             
             ax = gca;
             % ax.XTick = linspace(1,40,40); % ax.XTickLabel = num2cell(1:40);
             set(ax,'TickDir','out','YGrid','on','YMinorGrid','on','YMinorTick','on');
             
             Legend_Handle=legend([Bar_Handle(1) Bar_Handle(2) Bar_Handle(3) Bar_Handle(4)],{'Monomers','Multimerisation','HSPG','Lipoprotein'});
             set(Legend_Handle,'FontSize',30); legend('Location','best')
             set(Legend_Handle,'Position',[0.716666666666667 0.675764192139738 0.1828125 0.233624454148472],'LineWidth',1,'FontSize',30,'EdgeColor',[0 0 0]);
             set(gca,'box','off')
             ylim([0 inf]);
             if app.CheckBox.Value == true
                xlim([0 (n_size+1)]); 
             else
                 xlim([app.MinEditField.Value (app.MaxEditField.Value + 1)]);
             end
             
             % Gamma
             if app.CheckBox_2.Value == true
             Val_sum = Vals(1:n_size) + Vals((n_size+1):(2*n_size)) + Vals((2*n_size+1):(3*n_size));
             Val_sum2 = round(Val_sum);
             gam_out = gampdf(1:n_size,app.aEditField.Value,app.bEditField.Value);
             sum_gam = sum(gam_out);
             hold on
             XGrid = 1:n_size;
             YPlot = gam_out*(sum(Val_sum2)/sum_gam);
             plot(XGrid,YPlot,'LineWidth',3,'LineStyle','-.',...
                 'Color',[0.149019607843137 0.149019607843137 0.149019607843137], ...
                 'DisplayName','Gamma Distribution')
             end
             
             app.GenerateButton.BackgroundColor = [0.6314 0.902 0.5216];
             app.GenerateButton.Text = 'Generate';
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == true
                app.MinEditField.Visible = 'off';
                app.MinEditFieldLabel.Visible = 'off';
                app.MaxEditField.Visible = 'off';
                app.MaxEditFieldLabel.Visible = 'off';
            else
                app.MinEditField.Visible = 'on';
                app.MinEditFieldLabel.Visible = 'on';
                app.MaxEditField.Visible = 'on';
                app.MaxEditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: ResetParametersButton
        function ResetParametersButtonPushed(app, event)
            app.MonomerinputProteinsDayEditField.Value = 1152000;
            app.Sink1DayEditField.Value = 0.75;
            app.HSPGAdditionRateParticlesDayEditField.Value = 46000;
            app.LipoproteinAdditionRateParticlesDayEditField.Value = 60000;
            app.MultimerisationRate1MultimerDayEditField.Value = 0.0006;
            app.HSPGRecruitmentRate1MultimerDayEditField.Value = 0.0004;
            app.LipoproteinRecruitmentRate1MonomerDayEditField.Value = 0.0005;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.aEditField.Value = 0.75;
            app.bEditField.Value = 6.3;
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == true
                app.aEditField.Visible = 'on';
                app.aEditFieldLabel.Visible = 'on';
                app.bEditField.Visible = 'on';
                app.bEditFieldLabel.Visible = 'on';
                app.ResetButton.Visible = 'on';
            else
                app.aEditField.Visible = 'off';
                app.aEditFieldLabel.Visible = 'off';
                app.bEditField.Visible = 'off';
                app.bEditFieldLabel.Visible = 'off';
                app.ResetButton.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShhAggregationFigurePlotLabel = uilabel(app.UIFigure);
            app.ShhAggregationFigurePlotLabel.Text = 'Shh Aggregation Figure Plot';
            app.LargestmonomerassociationEditFieldLabel = uilabel(app.UIFigure);
            app.LargestmonomerassociationEditFieldLabel.Text = 'Largest monomer association';
            app.LargestmonomerassociationEditField = uieditfield(app.UIFigure, 'numeric');
            app.LargestmonomerassociationEditField.Limits = [2 10000];
            app.LargestmonomerassociationEditField.RoundFractionalValues = 'on';
            app.LargestmonomerassociationEditField.Value = 40;
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.MonomerinputProteinsDayEditFieldLabel = uilabel(app.ParametersPanel);
            app.MonomerinputProteinsDayEditFieldLabel.Text = 'Monomer input (Proteins/Day)';
            app.MonomerinputProteinsDayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MonomerinputProteinsDayEditField.Limits = [0 1000000000];
            app.MonomerinputProteinsDayEditField.RoundFractionalValues = 'on';
            app.MonomerinputProteinsDayEditField.ValueDisplayFormat = '%.0f';
            app.MonomerinputProteinsDayEditField.Value = 1152000;
            app.Sink1DayEditFieldLabel = uilabel(app.ParametersPanel);
            app.Sink1DayEditFieldLabel.Text = 'Sink (1/Day)';
            app.Sink1DayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.Sink1DayEditField.Limits = [0 1000000000];
            app.Sink1DayEditField.ValueDisplayFormat = '%.5f';
            app.Sink1DayEditField.Value = 0.75;
            app.HSPGAdditionRateParticlesDayEditFieldLabel = uilabel(app.ParametersPanel);
            app.HSPGAdditionRateParticlesDayEditFieldLabel.Text = 'HSPG Addition Rate (Particles/Day)';
            app.HSPGAdditionRateParticlesDayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.HSPGAdditionRateParticlesDayEditField.Limits = [0 1000000000];
            app.HSPGAdditionRateParticlesDayEditField.RoundFractionalValues = 'on';
            app.HSPGAdditionRateParticlesDayEditField.ValueDisplayFormat = '%.0f';
            app.HSPGAdditionRateParticlesDayEditField.Value = 46000;
            app.LipoproteinAdditionRateParticlesDayEditFieldLabel = uilabel(app.ParametersPanel);
            app.LipoproteinAdditionRateParticlesDayEditFieldLabel.Text = 'Lipoprotein Addition Rate (Particles/Day)';
            app.LipoproteinAdditionRateParticlesDayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LipoproteinAdditionRateParticlesDayEditField.Limits = [0 1000000000];
            app.LipoproteinAdditionRateParticlesDayEditField.RoundFractionalValues = 'on';
            app.LipoproteinAdditionRateParticlesDayEditField.ValueDisplayFormat = '%.0f';
            app.LipoproteinAdditionRateParticlesDayEditField.Value = 60000;
            app.MultimerisationRate1MultimerDayLabel = uilabel(app.ParametersPanel);
            app.MultimerisationRate1MultimerDayLabel.Text = 'Multimerisation Rate (1 / (Multimer*Day)';
            app.MultimerisationRate1MultimerDayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MultimerisationRate1MultimerDayEditField.Limits = [0 1000000000];
            app.MultimerisationRate1MultimerDayEditField.ValueDisplayFormat = '%e';
            app.MultimerisationRate1MultimerDayEditField.Value = 0.0006;
            app.HSPGRecruitmentRate1MultimerDayEditFieldLabel = uilabel(app.ParametersPanel);
            app.HSPGRecruitmentRate1MultimerDayEditFieldLabel.Text = 'HSPG Recruitment Rate (1 / (Multimer*Day)';
            app.HSPGRecruitmentRate1MultimerDayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.HSPGRecruitmentRate1MultimerDayEditField.Limits = [0 1000000000];
            app.HSPGRecruitmentRate1MultimerDayEditField.ValueDisplayFormat = '%e';
            app.HSPGRecruitmentRate1MultimerDayEditField.Value = 0.0004;
            app.LipoproteinRecruitmentRate1MonomerDayEditFieldLabel = uilabel(app.ParametersPanel);
            app.LipoproteinRecruitmentRate1MonomerDayEditFieldLabel.Text = 'Lipoprotein Recruitment Rate (1 / (Monomer*Day)';
            app.LipoproteinRecruitmentRate1MonomerDayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LipoproteinRecruitmentRate1MonomerDayEditField.Limits = [0 1000000000];
            app.LipoproteinRecruitmentRate1MonomerDayEditField.ValueDisplayFormat = '%e';
            app.LipoproteinRecruitmentRate1MonomerDayEditField.Value = 0.0005;
            app.ResetParametersButton = uibutton(app.ParametersPanel, 'push');
            app.ResetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParametersButtonPushed, true);
            app.ResetParametersButton.Text = 'Reset Parameters';
            app.AdditionalPanel = uipanel(app.UIFigure);
            app.AdditionalPanel.Title = 'Additional';
            app.LargestRecruitableMultimerEditFieldLabel = uilabel(app.AdditionalPanel);
            app.LargestRecruitableMultimerEditFieldLabel.Tooltip = {'The largest sized multimer that can be recruited by HSPGs.'};
            app.LargestRecruitableMultimerEditFieldLabel.Text = 'Largest Recruitable Multimer';
            app.LargestRecruitableMultimerEditField = uieditfield(app.AdditionalPanel, 'numeric');
            app.LargestRecruitableMultimerEditField.Limits = [0 1000];
            app.LargestRecruitableMultimerEditField.RoundFractionalValues = 'on';
            app.LargestRecruitableMultimerEditField.ValueDisplayFormat = '%.0f';
            app.LargestRecruitableMultimerEditField.Tooltip = {'Must be less than the monomer association.'};
            app.LargestRecruitableMultimerEditField.Value = 10;
            app.FigureOptionsPanel = uipanel(app.UIFigure);
            app.FigureOptionsPanel.Title = 'Figure Options';
            app.PrinttofigureEditFieldLabel = uilabel(app.FigureOptionsPanel);
            app.PrinttofigureEditFieldLabel.Tooltip = {'The largest sized multimer that can be recruited by HSPGs.'};
            app.PrinttofigureEditFieldLabel.Text = 'Print to figure';
            app.PrinttofigureEditField = uieditfield(app.FigureOptionsPanel, 'numeric');
            app.PrinttofigureEditField.Limits = [1 1000];
            app.PrinttofigureEditField.RoundFractionalValues = 'on';
            app.PrinttofigureEditField.ValueDisplayFormat = '%.0f';
            app.PrinttofigureEditField.Tooltip = {'Generating plots will by default print to Figure 1. This will overwrite any existing plot. Change the value to save a new plot to a different Figure.'};
            app.PrinttofigureEditField.Value = 1;
            app.CheckBox = uicheckbox(app.FigureOptionsPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.DefaultxAxisLimitsLabel = uilabel(app.FigureOptionsPanel);
            app.DefaultxAxisLimitsLabel.Text = {'Default x-Axis Limits'; ''};
            app.MinEditFieldLabel = uilabel(app.FigureOptionsPanel);
            app.MinEditFieldLabel.Visible = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.FigureOptionsPanel, 'numeric');
            app.MinEditField.Visible = 'off';
            app.MaxEditFieldLabel = uilabel(app.FigureOptionsPanel);
            app.MaxEditFieldLabel.Visible = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.FigureOptionsPanel, 'numeric');
            app.MaxEditField.Visible = 'off';
            app.MaxEditField.Value = 40;
            app.GammaDistributionOverlayLabel = uilabel(app.FigureOptionsPanel);
            app.GammaDistributionOverlayLabel.Text = 'Gamma Distribution Overlay';
            app.CheckBox_2 = uicheckbox(app.FigureOptionsPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.aEditFieldLabel = uilabel(app.FigureOptionsPanel);
            app.aEditFieldLabel.Visible = 'off';
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.FigureOptionsPanel, 'numeric');
            app.aEditField.Visible = 'off';
            app.aEditField.Value = 0.75;
            app.bEditFieldLabel = uilabel(app.FigureOptionsPanel);
            app.bEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.FigureOptionsPanel, 'numeric');
            app.bEditField.Visible = 'off';
            app.bEditField.Value = 6.3;
            app.ResetButton = uibutton(app.FigureOptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.CellAssociatedButton = uiradiobutton(app.ButtonGroup);
            app.CellAssociatedButton.Text = 'Cell Associated';
            app.CellAssociatedButton.Value = true;
            app.DispersedButton = uiradiobutton(app.ButtonGroup);
            app.DispersedButton.Text = 'Dispersed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppBuild

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end